classdef gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        RenderButton                matlab.ui.control.Button
        MaskToolsButtonGroup        matlab.ui.container.ButtonGroup
        subtractmask                matlab.ui.control.StateButton
        addmask                     matlab.ui.control.StateButton
        C                           matlab.ui.control.ToggleButton
        B                           matlab.ui.control.ToggleButton
        A                           matlab.ui.control.ToggleButton
        GridLayout3                 matlab.ui.container.GridLayout
        CurrentPositionEditField    matlab.ui.control.EditField
        ButtonGroup                 matlab.ui.container.ButtonGroup
        RadialLinesCheckBox         matlab.ui.control.CheckBox
        MaskButton                  matlab.ui.control.RadioButton
        RearWindowButton            matlab.ui.control.RadioButton
        VanishPointButton           matlab.ui.control.RadioButton
        OpenFileButton              matlab.ui.control.Button
        CurrentPositionEditField_2  matlab.ui.control.EditField
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % flags
        drawVP_flag     % wether VP is defined and drawed
        drawRW_flag     % wether inner Rectangle(Rear Window) is drawed
        
        InputImage      % documentation of the Image (m x n) uint8
        Img             % Image Object

        % spider mesh
        OuterRect       % specify the four corners of outer rectangle
                        % upleft-upright-downright-downleft
        OuterFixies

        InnerRect
        VanishPoint
        
        OuterSpiderPoints
        
        foreground_mask
        
    end
    
    properties (Access = public)
        draw_outer_rect     % control the drawing of outer rect
        draw_inner_rect     % control the drawing of inner rect
        draw_vp             % * of Vanish Point
        draw_radial         % * of Radial Lines

        draw_spider         % * of Rpider Lines
        
        radial_size
        select_process
    end
    
    % specify VanishPoint, InnerRect, OuterRect and RadialLines (function)
    methods (Access = private)
        
        % check if mouse is within axes
        function [new_point, isInAxes] = check_mouse_within_axes(app)
            isInAxes = true;
            cp = app.UIAxes.CurrentPoint(1,1:2);
            if cp(1) < 0 % x-axis
                cp(1) = 0;
                isInAxes = false;
            elseif cp(1) > size(app.InputImage,2)
                cp(1) = size(app.InputImage,2);
                isInAxes = false;
            end
            if cp(2) < 0 % y-axis
                cp(2) = 0;
                isInAxes = false;
            elseif cp(2) > size(app.InputImage,1)
                cp(2) = size(app.InputImage,1);
                isInAxes = false;
            end
            new_point = cp;
        end
        
        % draw the initial vanishpoint and radiallines
        function specify_VanishPoint(app)
            % check wether the VP is drawed
            if app.drawVP_flag
                return
            end
            % check weheter the mouse is within outerrect and plot the selection of VanishPoint
            [~, isInAxes] = app.check_mouse_within_axes();
            if ~isInAxes
                return
            end
            app.draw_vp = drawpoint(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor'};
            ValueArray = {'VanishPoint', 0, 'white'};
            set(app.draw_vp, NameArray, ValueArray);
            app.drawVP_flag = true;
        end

        % draw the inner rectangle
        function specify_RearWindow(app)
            if app.drawRW_flag
                return
            end
            [~, isInAxes] = app.check_mouse_within_axes();
            if ~isInAxes
                return
            end
            app.draw_inner_rect = drawrectangle(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'FaceSelectable'};
            ValueArray = {'InnerRectangle', 0, 'white', 0, false};
            set(app.draw_inner_rect, NameArray, ValueArray);
            app.drawRW_flag = true;
            app.draw_spider = [];
            app.InnerRect = app.draw_inner_rect.Position;
            for i = 1:length(app.OuterSpiderPoints)
                spider_line = [app.InnerRect(i), app.OuterSpiderPoints(i)];
                app.draw_spider(i) = drawline(app.UIAxes, 'Position', spider_line', 'InteractionsAllowed', 'none', 'Linewidth', 1);
            end
        end

        function specify_RadialLines(app)
            if ~app.drawVP_flag || ~app.drawRW_flag
                return
            end
            % plot the radial lines
            vp = app.VanishPoint;
            app.draw_radial = [];
            hold(app.UIAxes,'on');

            %%% need to be optimized
            for l = 1:length(app.OuterFixies)
                radial_line = [vp app.OuterFixies(:,l)];
                app.draw_radial(l) = drawline(app.UIAxes, 'Position', radial_line', 'InteractionsAllowed', 'none', 'Linewidth', 0.5);
            end
            hold(app.UIAxes,'off')
        end

        function specify_OuterRectangle(app)
            app.OuterRect = [[0,0]',[size(app.InputImage,2),0]',[size(app.InputImage,2),size(app.InputImage,1)]',[0,size(app.InputImage,1)]'];
            app.draw_outer_rect = drawrectangle(app.UIAxes, 'Position', [0,0,size(app.InputImage,2),size(app.InputImage,1)]);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'InteractionsAllowed'};
            ValueArray = {'OuterRectangle', 0, 'white', 0, 'none',};
            set(app.draw_outer_rect, NameArray, ValueArray);

            % preparation of radiallines based on outer_fixies
            app.OuterFixies = zeros(2,app.radial_size*length(app.OuterRect));
            n = 1;
            for i = 1:app.radial_size
                for j = 1:length(app.OuterRect)
                    app.OuterFixies(:, n) = [round(i*app.OuterRect(1,j)/app.radial_size); app.OuterRect(2,j)];
                    app.OuterFixies(:, n+1) = [app.OuterRect(1,j); round(i*app.OuterRect(2,j)/app.radial_size)];
                    n = n + 2;
                end
            end
            app.OuterFixies = unique(app.OuterFixies', 'rows')';
        end
        
    function wp = get_world_from_original(~, pt, area, vp, inner, dim)
    % get 3D point from 2d point
    %
    % :param pt: 1x2 input point [x, y]
    % :param area: string from ("Floor", ...)
    % :param vp: 1x2 vanish point [x, y]
    % :param inner: 1x4 values inner rectangle [x_min, y_min, x_max, y_max]
    % :returns: 1x3
    f= 1;
    in_lft = inner(1);
    in_btm = inner(2);
    in_rgt = inner(3);
    in_top = inner(4);
%     vp_x = vp(1);
%     vp_y = vp(2);
    
    d_btm = (vp(2) * f / (vp(2) - in_btm)) - f;
    d_top = ((dim(2) - vp(2)) * f/ (in_top - vp(2))) - f;
    d_lft = (vp(1) * f / (vp(1) - in_lft)) - f;
    d_rgt = ((dim(1) - vp(1)) * f/ (in_rgt - vp(1))) - f;
%     d_room = max(max(d_btm, d_top), max(d_lft, d_rgt));
    
    
    if area == "Floor"
        wpy = 0;
        delta_lft = pt(1) - vp(1);
        delta_btm = vp(2) - pt(2);
        wpz = d_btm - (vp(2) / delta_btm * f -f);
        dinv = f + d_btm - wpz;
        wpx = vp(1) + delta_lft / f * dinv;
    end
    
    if area == "Ceiling"
        wpy = dim(2);
        delta_lft = pt(1) - vp(1);
        delta_top = pt(2) - vp(2);
        wpz = d_top - ((dim(2) - vp(2))/delta_top * f - f);
        dinv = f + d_top - wpz;
        wpx = vp(1) + delta_lft / f * dinv;
        
        
    end
    
    if area == "Rear"
        wpx = (f+d_lft) / f * (pt(1) - in_lft); % d_room here?
        wpy = (f+d_btm) / f * (pt(2) - in_btm); % d_room here?
        wpz = 0;
        
    end
    
    if area == "Left"
        wpx = 0;
        delta_lft = vp(1) - pt(1);
        delta_btm = pt(2) - vp(2);
        wpz = d_lft - (vp(1)/delta_lft * f - f);
        dinv = f + d_btm - wpz;
        wpy = vp(2) + delta_btm / f * dinv;
        
    end
    
    if area == "Right"
        wpx = dim(1);
        delta_rgt = pt(1) - vp(1);
        delta_btm = pt(2) - vp(2);
        wpz = d_rgt - ((dim(1) - vp(1)) / delta_rgt * f - f);
        dinv = f + d_btm - wpz;
        wpy = vp(2) + delta_btm / f * dinv;
    end
    wp = [wpx, wpy, wpz];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');
            title(app.UIAxes,'');
            xlabel(app.UIAxes,'');
            ylabel(app.UIAxes,'');
            
            app.radial_size = 5;
            app.drawVP_flag = false;
            app.drawRW_flag = false;
            app.select_process = 1;
            app.RadialLinesCheckBox.Value = 0;
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [file,path,FilterIndex] = uigetfile('*.*','Select an Image File');
            startupFcn(app);
            if FilterIndex == 0
                return;
            end
            app.InputImage = imread(fullfile(path,file));
            app.Img = imshow(app.InputImage,'Parent',app.UIAxes);
            app.specify_OuterRectangle();
            app.OuterSpiderPoints = app.OuterRect;
            app.foreground_mask = zeros(size(app.InputImage));
            %app.size_image = size(app.InputImage);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            tf = strcmp(app.UIFigure.SelectionType, 'normal');
            if tf == 1 % left click of the mouse
                switch app.select_process
                    case 1
                        app.specify_VanishPoint();
                        set(app.draw_vp,'InteractionsAllowed','all');
                        set(app.draw_inner_rect,'InteractionsAllowed','none')
                    case 2
                        app.specify_RearWindow();
                        set(app.draw_vp,'InteractionsAllowed','none');
                        set(app.draw_inner_rect,'InteractionsAllowed','all')           
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            set(app.CurrentPositionEditField,'Value',sprintf('%.2f, %.2f',app.UIAxes.CurrentPoint(1,1:2)))
            if app.drawVP_flag && app.select_process == 1
                app.VanishPoint = app.draw_vp.Position';
                if app.RadialLinesCheckBox.Value
                    delete(app.draw_radial)
                    app.specify_RadialLines();
                end
            end
            if app.drawRW_flag && app.select_process == 2
                app.InnerRect = app.draw_inner_rect.Position;
            end
            if app.select_process == 3            
                selectedButton = app.MaskToolsButtonGroup.SelectedObject;
                if (selectedButton == app.A)
                    roi = images.roi.AssistedFreehand('Image', app.Img);
                    draw(roi);
                    new_mask = createMask(roi, app.InputImage);
                    update_mask(app.foreground_mask, new_mask, app.subtractmask.Value)
                end
            end
            if app.drawVP_flag && app.drawRW_flag
                set(app.CurrentPositionEditField_2,'Value',sprintf('%.1f, %.1f, %.1f',app.get_world_from_original(app.UIAxes.CurrentPoint(1,1:2), "Floor", app.VanishPoint, app.InnerRect, [1152, 829])))
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'VanishPoint')
                app.select_process = 1;
                app.MaskToolsButtonGroup.Enable = "off";
            elseif strcmp(selectedButton.Text,'RearWindow')
                app.select_process = 2;
                app.MaskToolsButtonGroup.Enable = "off";
            elseif strcmp(selectedButton.Text,'Mask')
                app.select_process = 3;
                app.MaskToolsButtonGroup.Enable = "on";
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            
        end

        % Value changed function: RadialLinesCheckBox
        function RadialLinesCheckBoxValueChanged(app, event)
            value = app.RadialLinesCheckBox.Value;
            if value == true
                app.specify_RadialLines();
            else
                delete(app.draw_radial);
            end
        end

        % Value changed function: addmask
        function addmaskValueChanged(app, event)
            value = app.addmask.Value;
            app.addmask.Value = 1;
            app.subtractmask.Value = 0;
            
        end

        % Value changed function: subtractmask
        function subtractmaskValueChanged(app, event)
            value = app.subtractmask.Value;
            app.subtractmask.Value = 1;
            app.addmask.Value = 0;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.RenderButton = uibutton(app.GridLayout2, 'push');
            app.RenderButton.Text = 'Render';
            app.MaskToolsButtonGroup = uibuttongroup(app.GridLayout2);
            app.MaskToolsButtonGroup.Enable = 'off';
            app.MaskToolsButtonGroup.Title = 'Mask Tools';
            app.subtractmask = uibutton(app.MaskToolsButtonGroup, 'state');
            app.subtractmask.ValueChangedFcn = createCallbackFcn(app, @subtractmaskValueChanged, true);
            app.subtractmask.Text = '-';
            app.addmask = uibutton(app.MaskToolsButtonGroup, 'state');
            app.addmask.ValueChangedFcn = createCallbackFcn(app, @addmaskValueChanged, true);
            app.addmask.Text = '+';
            app.addmask.Value = true;
            app.C = uitogglebutton(app.MaskToolsButtonGroup);
            app.C.Text = 'B';
            app.B = uitogglebutton(app.MaskToolsButtonGroup);
            app.B.Text = 'C';
            app.A = uitogglebutton(app.MaskToolsButtonGroup);
            app.A.Text = 'A';
            app.A.Value = true;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CurrentPositionEditField = uieditfield(app.GridLayout3, 'text');
            app.ButtonGroup = uibuttongroup(app.GridLayout3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.RadialLinesCheckBox = uicheckbox(app.ButtonGroup);
            app.RadialLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RadialLinesCheckBoxValueChanged, true);
            app.RadialLinesCheckBox.Text = 'RadialLines';
            app.MaskButton = uiradiobutton(app.ButtonGroup);
            app.MaskButton.Tag = 'R';
            app.MaskButton.Text = 'Mask';
            app.RearWindowButton = uiradiobutton(app.ButtonGroup);
            app.RearWindowButton.Tag = 'R';
            app.RearWindowButton.Text = 'RearWindow';
            app.VanishPointButton = uiradiobutton(app.ButtonGroup);
            app.VanishPointButton.Tag = 'V';
            app.VanishPointButton.Text = 'VanishPoint';
            app.VanishPointButton.Value = true;
            app.OpenFileButton = uibutton(app.GridLayout3, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.CurrentPositionEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end